classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        DisplayResult            matlab.ui.control.Table
        userGuideLabel           matlab.ui.control.Label
        testLabel                matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        FlowrateQEditFieldLabel  matlab.ui.control.Label
        FlowrateQEditField       matlab.ui.control.NumericEditField
        ViscosityEditFieldLabel  matlab.ui.control.Label
        ViscosityEditField       matlab.ui.control.NumericEditField
        PressurePEditFieldLabel  matlab.ui.control.Label
        PressurePEditField       matlab.ui.control.NumericEditField
        RPMNEditFieldLabel       matlab.ui.control.Label
        RPMNEditField            matlab.ui.control.NumericEditField
        mhrLabel                 matlab.ui.control.Label
        cStLabel                 matlab.ui.control.Label
        barLabel                 matlab.ui.control.Label
        nLabel                   matlab.ui.control.Label
        ViscosityEditFieldLabel  matlab.ui.control.Label
        ViscosityEditField       matlab.ui.control.NumericEditField
        PressurePEditFieldLabel  matlab.ui.control.Label
        PressurePEditField       matlab.ui.control.NumericEditField
        ViscosityEditFieldLabel  matlab.ui.control.Label
        ViscosityEditField       matlab.ui.control.NumericEditField
        PressurePEditFieldLabel  matlab.ui.control.Label
        PressurePEditField       matlab.ui.control.NumericEditField
        ViscosityEditFieldLabel  matlab.ui.control.Label
        ViscosityEditField       matlab.ui.control.NumericEditField
        PressurePEditFieldLabel  matlab.ui.control.Label
        PressurePEditField       matlab.ui.control.NumericEditField
        ViscosityEditFieldLabel  matlab.ui.control.Label
        ViscosityEditField       matlab.ui.control.NumericEditField
        PressurePEditFieldLabel  matlab.ui.control.Label
        PressurePEditField       matlab.ui.control.NumericEditField
        ViscosityEditFieldLabel  matlab.ui.control.Label
        ViscosityEditField       matlab.ui.control.NumericEditField
        PressurePEditFieldLabel  matlab.ui.control.Label
        PressurePEditField       matlab.ui.control.NumericEditField
        ViscosityEditFieldLabel  matlab.ui.control.Label
        ViscosityEditField       matlab.ui.control.NumericEditField
        PressurePEditFieldLabel  matlab.ui.control.Label
        PressurePEditField       matlab.ui.control.NumericEditField
        ViscosityEditFieldLabel  matlab.ui.control.Label
        ViscosityEditField       matlab.ui.control.NumericEditField
        PressurePEditFieldLabel  matlab.ui.control.Label
        PressurePEditField       matlab.ui.control.NumericEditField
        ViscosityEditFieldLabel  matlab.ui.control.Label
        ViscosityEditField       matlab.ui.control.NumericEditField
        PressurePEditFieldLabel  matlab.ui.control.Label
        PressurePEditField       matlab.ui.control.NumericEditField
        TotalresultsLabel        matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        UITableResults           matlab.ui.control.Table
        okButton                 matlab.ui.control.Button
        Tab2                     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        data = load('data_sheet_all_mod.mat');
        data2 = load('data_2_sheet_all.mat');
        
        currentFlowrateData
        currentViscocityData
        currentPrassureData
        flowrate = 500;
        viscosity = 15000;
        pressure = 12;
        flowrateVar = 0;
        viscosityVar = 0;
        pressureVar = 0;
        guideStr
        fl
        pr
        vi
        RPM = 750;
    end
    
    methods (Access = private)
        % find flowrate
        function totalResults(app,len)
            app.TotalresultsLabel.Text = strcat('Total results :',{' '},num2str(len));
            app.TotalresultsLabel.Visible = 'on';
            
            %             plotting data
            selected_data = app.DisplayResult.Data;
            ax = app.UIAxes;
            %             plot(ax,selected_data);
            %             legend(ax,'Flowrate','Power','viscosity','Pressure','Diameter','pitch');
        end
        
        %         data2 functions
        
        function result = calcQTH(app,RPM,fl)
            result = (1000 * fl) / RPM;
        end
        
        function result = calcSheet2(app,s2_QTH)
            %             selection of sheet 2 data
            s2 = app.data2.s2;
            QTH = app.data2.s2(:,3);
            % finding nearest value
            [ d,iQTH] = min( abs( QTH-s2_QTH));
            
            maxQTH = app.data2.s2(iQTH,:);
            
            try
                minQTH  = app.data2.s2(iQTH - 1,:);
            catch
                minQTH = maxQTH;
            end

        
            result = [minQTH;maxQTH];
        end
        
        function result = calcSheet3(app,pr)
            %             take pressure as an input
            s3 = app.data2.s3;
            
            [ ~,coeffPressure] = min( abs( s3(:,1)-pr));
            
            result = s3(coeffPressure,:);
        end
        
        function result = calcSheet4(app,vi)
            %             take viscosity as an input
            if vi < 1500
                vi = vi;
            else
                vi = 1500;
            end
            
            vi
            
            s4 = app.data2.s4;
            
            [ ~,ss4] = min( abs( s4(:,1)-vi));
            
            ss4
            
            coeffViscosity = find(s4(:,1) == vi);
            result = s4(coeffViscosity,:);
        end
        
        function result = calcSheet5(app,vi)
            %             taking viscosity as an input
            s5 = app.data2.s5;
            
            iVi = find(s5(:,1) == vi);
            result = s5(iVi,:);
        end
        
        function result = calcSheet6(app,RPM)
            %             taking input a RPM
            s6 = app.data2.s6;
            iRPM = find(s6(:,1) == RPM);
            result = s6(iRPM,:);
        end
        
        function result = calcQF(app,sheet2,sheet3,sheet4)
            min = sheet2(1,4);
            max = sheet2(2,4);
            
            cofP = sheet3(1,2);
            cofV = sheet4(1,2);
            
            maxQF = max * cofP * cofV;
            minQF = min * cofP * cofV;
            
            result = [minQF,maxQF]
        end
        
        function result = calcQR(app,sheet2,sheet6,QF)
            minQth = sheet2(1,3);
            maxQth = sheet2(2,3);
            
            
            N = sheet6(1); % n = RPM
            minQR = (minQth * N/1000) - QF(1);
            maxQR = (maxQth * N/1000) - QF(2);
            
            result = [minQR , maxQR];
        end
        
        function result = calcCoeffRPM(app,sheet6)
            RPM = sheet6(1);
            result = (RPM / 1500)^1.6;
        end
        
        function result = calcPp(app,sheet2,sheet5,sheet6)
            minPp = sheet2(1,5);
            maxPp = sheet2(2,5);
            
            coeffRPM = sheet6(2);
            coeffVisc = sheet5(2);
            
            minPP = minPp * coeffRPM * coeffVisc;
            maxPP = maxPp * coeffRPM * coeffVisc;
            
            result = [minPP,maxPP];
        end
        
        function result = calcPTH(app,sheet2,sheet3,sheet6)
            minQth = sheet2(1,3);
            maxQth = sheet2(2,3);
            
            deltaP = sheet3(1);
            
            N = sheet6(1);
            
            minPTH = (minQth * deltaP * N) / 27000;
            maxPTH = (maxQth * deltaP * N) / 27000;
            
            result = [minPTH,maxPTH];
        end
   
   
        function result = calcParams(app,fl,vi,pr,RPM,Qth)
            sheet2 = app.calcSheet2(Qth);
            sheet3 = app.calcSheet3(pr);
            sheet4 = app.calcSheet4(vi);
            sheet5 = app.calcSheet5(vi);
            sheet6 = app.calcSheet6(RPM);
            
            QF = app.calcQF(sheet2,sheet3,sheet4);
            QR = app.calcQR(sheet2,sheet6,QF);
            CoeffRPM = app.calcCoeffRPM(sheet6);
            PP = app.calcPp(sheet2,sheet5,sheet6);
            PTH = app.calcPTH(sheet2,sheet3,sheet6);
            
            pabs = PTH + PP;
            
            dim = sheet2(:,1);
            pitch = sheet2(:,2);
            
            result = [pabs',QR',pitch,dim];
           
        end
    
        function plotFlowrateVsPressure(app)
           ax = app.UIAxes;
           s3 = app.data2.s3;
           
            
           [~,index] = min( abs( app.pressure + 1 - s3(:,1)));
           
           fl = app.flowrate;
           vi = app.viscosity;
           pr = app.pressure;
           RPM = app.RPM;
           Qth = app.calcQTH(RPM,fl);
           
           QRs = [];
           PABSs = [];
           for i = (s3(:,1)')
                params = app.calcParams(fl,vi,i,RPM,Qth);           
                QR = params(2,2);
                PABS = params(2,1);
               
                QRs = [QRs,QR]
                PABSs = [PABSs,PABS]
           end
           
           inputPoints = app.calcParams(fl,vi,pr,RPM,Qth);

           
           plot(ax,s3(:,1),QRs');
           hold(ax,'on');
           plot(ax,s3(:,1),PABSs');
           plot(ax,pr,inputPoints(2,1),"*");
           plot(ax,pr,inputPoints(2,2),"*");
           hold(ax,'off');
           legend(ax,"QR","PABS");
           xlabel(ax,"pressure");
    
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DisplayResult.Visible = 'off';
            app.testLabel.Visible = 'off';
            % disable
            app.TotalresultsLabel.Visible = 'off';
            % defining user to do
            app.userGuideLabel.Text = 'add Flow rate to find diameter';
            
            
            %             data2 functions for the testing 
            
            fl = 500;
            vi = 15000;
            pr = 12;
            RPM = 750;
            Qth = app.calcQTH(RPM,fl);
            
            app.calcParams(fi,vi,pr,RPM,Qth);       
            
            
            Qths = app.data.s2(:,3);
            
            for i = (Qths)
                i
            end
            
        end

        % Value changed function: FlowrateQEditField
        function FlowrateQEditFieldValueChanged(app, event)
            value = app.FlowrateQEditField.Value;
            app.flowrate = value;
        end

        % Value changed function: ViscosityEditField
        function ViscosityEditFieldValueChanged(app, event)
            value = app.ViscosityEditField.Value;
            app.viscosity = value;
        end

        % Value changed function: PressurePEditField
        function PressurePEditFieldValueChanged(app, event)
            value = app.PressurePEditField.Value;
            app.pressure = value;
        end

        % Callback function: DisplayResult
        function DisplayResultCellSelection(app, event)
            eventData = event.Source.Data;
            
            selected_data = eventData(event.Indices(1),:);
            app.fl = selected_data(1);
            app.vi = selected_data(3);
            app.pr = selected_data(4);
            app.RPM = 950;
            
            Qth = app.calcQTH(app.RPM,app.fl);
            
            sheet2 = app.calcSheet2(Qth);
            sheet3 = app.calcSheet3(app.pr);
            sheet4 = app.calcSheet4(app.vi);
            sheet5 = app.calcSheet5(app.vi);
            sheet6 = app.calcSheet6(app.RPM);
            
            QF = app.calcQF(sheet2,sheet3,sheet4);
            QR = app.calcQR(sheet2,sheet6,QF);
            CoeffRPM = app.calcCoeffRPM(sheet6);
            PP = app.calcPp(sheet2,sheet5,sheet6);
            PTH = app.calcPTH(sheet2,sheet3,sheet6);
            
            pabs = PTH + PP
            
            results = [QR',[CoeffRPM;CoeffRPM],PP',PTH',pabs']
            
            app.UITableResults.Data = results;
            

            app.plotSheet3(app.pr);
            %             app.TabGroup.SelectedTab = app.Tab2
        end

        % Value changed function: RPMNEditField
        function RPMNEditFieldValueChanged(app, event)
            value = app.RPMNEditField.Value;
            app.RPM = value;
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            fl = app.flowrate;
            vi = app.viscosity;
            pr = app.pressure;
            RPM = app.RPM;
            Qth = app.calcQTH(RPM,fl);
            
            app.UITableResults.Data = app.calcParams(fi,vi,pr,RPM,Qth);
            
            app.plotFlowrateVsPressure();
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.DisplayResult = uitable(app.Tab);
            app.DisplayResult.ColumnName = {'m3/hr - Q'; 'kW - P'; 'Viscosity'; 'Pressure - bar'; 'Diameter'; 'Pitch'};
            app.DisplayResult.RowName = {};
            app.DisplayResult.CellEditCallback = createCallbackFcn(app, @DisplayResultCellSelection, true);
            app.DisplayResult.CellSelectionCallback = createCallbackFcn(app, @DisplayResultCellSelection, true);
            app.userGuideLabel = uilabel(app.Tab);
            app.userGuideLabel.Text = 'userGuide';
            app.testLabel = uilabel(app.Tab);
            app.testLabel.Text = 'test';
            app.Panel = uipanel(app.Tab);
            app.FlowrateQEditFieldLabel = uilabel(app.Panel);
            app.FlowrateQEditFieldLabel.Text = 'Flow rate - Q';
            app.FlowrateQEditField = uieditfield(app.Panel, 'numeric');
            app.FlowrateQEditField.ValueChangedFcn = createCallbackFcn(app, @FlowrateQEditFieldValueChanged, true);
            app.ViscosityEditFieldLabel = uilabel(app.Panel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity - μ';
            app.ViscosityEditField = uieditfield(app.Panel, 'numeric');
            app.ViscosityEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityEditFieldValueChanged, true);
            app.PressurePEditFieldLabel = uilabel(app.Panel);
            app.PressurePEditFieldLabel.Text = 'Pressure -  P';
            app.PressurePEditField = uieditfield(app.Panel, 'numeric');
            app.PressurePEditField.ValueChangedFcn = createCallbackFcn(app, @PressurePEditFieldValueChanged, true);
            app.RPMNEditFieldLabel = uilabel(app.Panel);
            app.RPMNEditFieldLabel.Text = 'RPM - N';
            app.RPMNEditField = uieditfield(app.Panel, 'numeric');
            app.RPMNEditField.ValueChangedFcn = createCallbackFcn(app, @RPMNEditFieldValueChanged, true);
            app.mhrLabel = uilabel(app.Panel);
            app.mhrLabel.Text = 'm³/hr';
            app.cStLabel = uilabel(app.Panel);
            app.cStLabel.Text = 'cSt';
            app.barLabel = uilabel(app.Panel);
            app.barLabel.Text = 'bar';
            app.nLabel = uilabel(app.Panel);
            app.nLabel.Text = 'n';
            app.ViscosityEditFieldLabel = uilabel(app.Panel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity - μ';
            app.ViscosityEditField = uieditfield(app.Panel, 'numeric');
            app.ViscosityEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityEditFieldValueChanged, true);
            app.PressurePEditFieldLabel = uilabel(app.Panel);
            app.PressurePEditFieldLabel.Text = 'Pressure -  P';
            app.PressurePEditField = uieditfield(app.Panel, 'numeric');
            app.PressurePEditField.ValueChangedFcn = createCallbackFcn(app, @PressurePEditFieldValueChanged, true);
            app.ViscosityEditFieldLabel = uilabel(app.Panel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity - μ';
            app.ViscosityEditField = uieditfield(app.Panel, 'numeric');
            app.ViscosityEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityEditFieldValueChanged, true);
            app.PressurePEditFieldLabel = uilabel(app.Panel);
            app.PressurePEditFieldLabel.Text = 'Pressure -  P';
            app.PressurePEditField = uieditfield(app.Panel, 'numeric');
            app.PressurePEditField.ValueChangedFcn = createCallbackFcn(app, @PressurePEditFieldValueChanged, true);
            app.ViscosityEditFieldLabel = uilabel(app.Panel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity - μ';
            app.ViscosityEditField = uieditfield(app.Panel, 'numeric');
            app.ViscosityEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityEditFieldValueChanged, true);
            app.PressurePEditFieldLabel = uilabel(app.Panel);
            app.PressurePEditFieldLabel.Text = 'Pressure -  P';
            app.PressurePEditField = uieditfield(app.Panel, 'numeric');
            app.PressurePEditField.ValueChangedFcn = createCallbackFcn(app, @PressurePEditFieldValueChanged, true);
            app.ViscosityEditFieldLabel = uilabel(app.Panel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity - μ';
            app.ViscosityEditField = uieditfield(app.Panel, 'numeric');
            app.ViscosityEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityEditFieldValueChanged, true);
            app.PressurePEditFieldLabel = uilabel(app.Panel);
            app.PressurePEditFieldLabel.Text = 'Pressure -  P';
            app.PressurePEditField = uieditfield(app.Panel, 'numeric');
            app.PressurePEditField.ValueChangedFcn = createCallbackFcn(app, @PressurePEditFieldValueChanged, true);
            app.ViscosityEditFieldLabel = uilabel(app.Panel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity - μ';
            app.ViscosityEditField = uieditfield(app.Panel, 'numeric');
            app.ViscosityEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityEditFieldValueChanged, true);
            app.PressurePEditFieldLabel = uilabel(app.Panel);
            app.PressurePEditFieldLabel.Text = 'Pressure -  P';
            app.PressurePEditField = uieditfield(app.Panel, 'numeric');
            app.PressurePEditField.ValueChangedFcn = createCallbackFcn(app, @PressurePEditFieldValueChanged, true);
            app.ViscosityEditFieldLabel = uilabel(app.Panel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity - μ';
            app.ViscosityEditField = uieditfield(app.Panel, 'numeric');
            app.ViscosityEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityEditFieldValueChanged, true);
            app.PressurePEditFieldLabel = uilabel(app.Panel);
            app.PressurePEditFieldLabel.Text = 'Pressure -  P';
            app.PressurePEditField = uieditfield(app.Panel, 'numeric');
            app.PressurePEditField.ValueChangedFcn = createCallbackFcn(app, @PressurePEditFieldValueChanged, true);
            app.ViscosityEditFieldLabel = uilabel(app.Panel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity - μ';
            app.ViscosityEditField = uieditfield(app.Panel, 'numeric');
            app.ViscosityEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityEditFieldValueChanged, true);
            app.PressurePEditFieldLabel = uilabel(app.Panel);
            app.PressurePEditFieldLabel.Text = 'Pressure -  P';
            app.PressurePEditField = uieditfield(app.Panel, 'numeric');
            app.PressurePEditField.ValueChangedFcn = createCallbackFcn(app, @PressurePEditFieldValueChanged, true);
            app.ViscosityEditFieldLabel = uilabel(app.Panel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity - μ';
            app.ViscosityEditField = uieditfield(app.Panel, 'numeric');
            app.ViscosityEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityEditFieldValueChanged, true);
            app.PressurePEditFieldLabel = uilabel(app.Panel);
            app.PressurePEditFieldLabel.Text = 'Pressure -  P';
            app.PressurePEditField = uieditfield(app.Panel, 'numeric');
            app.PressurePEditField.ValueChangedFcn = createCallbackFcn(app, @PressurePEditFieldValueChanged, true);
            app.TotalresultsLabel = uilabel(app.Tab);
            app.TotalresultsLabel.Text = 'Total results';
            app.UIAxes = uiaxes(app.Tab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITableResults = uitable(app.Tab);
            app.UITableResults.ColumnName = {'PABS'; 'QR'; 'Pitch'; 'Diameter'};
            app.UITableResults.RowName = {};
            app.okButton = uibutton(app.Tab, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'ok';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end