classdef pumpSqure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        RPMEditFieldLabel        matlab.ui.control.Label
        RPMEditField             matlab.ui.control.NumericEditField
        FlowrateEditFieldLabel   matlab.ui.control.Label
        FlowrateEditField        matlab.ui.control.NumericEditField
        ViscosityEditFieldLabel  matlab.ui.control.Label
        ViscosityEditField       matlab.ui.control.NumericEditField
        PresssureEditFieldLabel  matlab.ui.control.Label
        PresssureEditField       matlab.ui.control.NumericEditField
        okButton                 matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        UIAxes                   matlab.ui.control.UIAxes
        ButtonGroup              matlab.ui.container.ButtonGroup
        QRButton                 matlab.ui.control.RadioButton
        PABSButton               matlab.ui.control.RadioButton
    end


    properties (Access = private)
         data = load('data_2_sheet_all.mat');
         RPM = 750;
         fl = 500;
         vi = 15000;
         pr = 12;
         selectedCol = 1;
         QR = 0;
    end

    methods (Access = private)
    
        function result = sheet2(app)
            s2 = app.data.s2;
            colQth = s2(:,3);
            
            Qth = (1000 * app.fl) / app.RPM;
                
            % finding the closest value
            [d,iQth] = min( abs(Qth-colQth'));
            
            try
                minQTH = app.data.s2(iQth - 1,:);
                maxQTH = app.data.s2(iQth,:);
            catch
                minQTH = app.data.s2(iQth,:);
                maxQTH = app.data.s2(iQth + 1,:);                
            end
            
        % returning Qth min then Qth max
            result = [minQTH;maxQTH];
        end
    
        function result = sheet3(app)
            s3 = app.data.s3;
            colPr = s3(:,1);
            % finding the closest value
            [d,iPr] = min(abs(colPr - app.pr));

            result = s3(iPr,:);
        end
    
        function result = sheet4(app)
            s4 = app.data.s4;
            colVi = s4(:,1);
            % finding the closest value
            [d,iVi] = min(abs(colVi - app.vi));
            result = s4(iVi,:);
        end
    
        function result = sheet5(app)
            s5 = app.data.s5;
            colVi = s5(:,1);
            % finding the closest value
            [d,iVi] = min(abs(colVi - app.vi));
            result = s5(iVi,:);
        end
    
        function result = sheet6(app)
            s6 = app.data.s6;
            colRPM = s6(:,1);
            % finding the closest value
            [d,iRPM] = min(abs(colRPM - app.RPM));
            result = s6(iRPM,:);
        end
    
        function result = calcQF(app,sheet2,sheet3,sheet4)
            f = sheet2(1,4);
            coBar = sheet3(1,2);
            coVi = sheet4(1,2);
            
            result = f * coBar * coVi;
        end
    
        function result = calcQR(app,sheet2,sheet6,QF)
            QTH = sheet2(1,3);
            n = sheet6(1,1);
            result = (QTH * n)/1000 - QF;
        end
    
        function result = calcCoRPM(app)
            RPM = app.RPM;
            
            result = (RPM/1500)^1.6;
        end
        
        function result = caclPP(app,sheet2,sheet5,sheet6)
            pp = sheet2(1,5);
            coRPM = sheet6(1,2);
            coVi = sheet5(1,2);
            
            result = pp * coRPM * coVi;
        end
    
        function result = calcPTH(app,sheet2,sheet3,sheet6)
            QTH = sheet2(1,3);
            deltaP = sheet3(1,1);
            n = sheet6(1,1); % QUESTION: we can have the RPM from the user input as well which one is prefferd?
            
            result = (QTH * deltaP * n)/27000;
        end
    
        function result = calcParams(app,index)
            sheet2All = app.sheet2();
            sheet2 = sheet2All(index,:); % taking 2st row by default we can adjust the Qth in the loop
            sheet3 = app.sheet3();
            sheet4 = app.sheet4();
            sheet5 = app.sheet5();
            sheet6 = app.sheet6();
            
            QF = app.calcQF(sheet2,sheet3,sheet4);
            QR = app.calcQR(sheet2,sheet6,QF);
            calcCoRPM = app.calcCoRPM(); % QUESTION: this variable have not used even once, why?
            PP = app.caclPP(sheet2,sheet5,sheet6);
            PTH = app.calcPTH(sheet2,sheet3,sheet6);
            PABS = PTH + PP;
            app.QR  = QR;
           
            % output of all answer in the 1 * 4 matrix [PABS,QR,Pitch,Diameter]
            pitch = sheet2(1,2);
            diameter = sheet2(1,1);
            result = [PABS,QR,pitch,diameter];
        end
    
        function genPlot(app,index,plotSelection)
            s3 = app.data.s3;
            sheet2All = app.sheet2();
            sheet2 = sheet2All(index,:); % taking 2st row by default we can adjust the Qth in the loop
            sheet3 = app.sheet3();
            sheet4 = app.sheet4();
            sheet5 = app.sheet5();
            sheet6 = app.sheet6();
            PP = app.caclPP(sheet2,sheet5,sheet6);
          
            % QF QR PTH PABS need to be in the loop
            QFs = [];
            QRs = [];
            PTHs = [];
            PABSs = [];
            index = 0;
            for pr = (s3()')
                pr = pr';
                
                QF = app.calcQF(sheet2,pr,sheet4);
                QR = app.calcQR(sheet2,sheet6,QF);
                PTH = app.calcPTH(sheet2,pr,sheet6);
                PABS = PTH + PP;
                
                QRs = [QRs;QR];           
                QFs = [QFs;QF];
                PABSs = [PABSs;PABS];
            end
            
            % results [n.3] matrix QRs QFs PABSs
            % result = [QFs, QRs, QFs, PABSs]
            prams = app.calcParams(app.selectedCol);
            pQR = prams(1,2);
            pPABS = prams(1,1);
            ax = app.UIAxes();
            
            if strcmp(plotSelection,'QR')
                plot(ax,s3(:,1),QRs);
                hold(ax,'on');
                plot(ax,sheet3(:,1),pQR,'*');
                hold(ax,'off');
            else
                plot(ax,s3(:,1),PABSs);
                hold(ax,'on');
                plot(ax,sheet3(:,1),pPABS,'*');
                hold(ax,'off');
            end
            
            xlabel(ax,"PRESSURE");
            ylabel(ax,plotSelection);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.genPlot(2,'QR');
%             min = app.calcParams(1);
%             max = app.calcParams(2);
%             
%             tableData = [min;max];
%             app.UITable.Data = tableData;
        end

        % Value changed function: RPMEditField
        function RPMEditFieldValueChanged(app, event)
            value = app.RPMEditField.Value;
            app.RPM = value;
        end

        % Value changed function: FlowrateEditField
        function FlowrateEditFieldValueChanged(app, event)
            value = app.FlowrateEditField.Value;
            app.fl = value;
        end

        % Value changed function: ViscosityEditField
        function ViscosityEditFieldValueChanged(app, event)
            value = app.ViscosityEditField.Value;
            app.vi = value;
        end

        % Value changed function: PresssureEditField
        function PresssureEditFieldValueChanged(app, event)
            value = app.PresssureEditField.Value;
            app.pr = value;
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            plotSelection = app.ButtonGroup.SelectedObject.Text;
            app.genPlot(app.selectedCol,plotSelection);

            min = app.calcParams(1);
            max = app.calcParams(2);
            
            app.QR = max(1,2);
            
%             while app.QR < 130
%                 max = app.calcParams(1);
%                 app.QR = max(1,2);
%             end
            
            tableData = [min;max];
            app.UITable.Data = tableData; 
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
             selection = event.Indices;
             app.selectedCol = selection(:,1);
             
             plotSelection = app.ButtonGroup.SelectedObject.Text;
             app.genPlot(app.selectedCol,plotSelection);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            selected = selectedButton.Text;
            
            app.genPlot(app.selectedCol,selected);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.RPMEditFieldLabel = uilabel(app.Panel);
            app.RPMEditFieldLabel.Text = 'RPM';
            app.RPMEditField = uieditfield(app.Panel, 'numeric');
            app.RPMEditField.ValueChangedFcn = createCallbackFcn(app, @RPMEditFieldValueChanged, true);
            app.FlowrateEditFieldLabel = uilabel(app.Panel);
            app.FlowrateEditFieldLabel.Text = 'Flowrate';
            app.FlowrateEditField = uieditfield(app.Panel, 'numeric');
            app.FlowrateEditField.ValueChangedFcn = createCallbackFcn(app, @FlowrateEditFieldValueChanged, true);
            app.ViscosityEditFieldLabel = uilabel(app.Panel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity';
            app.ViscosityEditField = uieditfield(app.Panel, 'numeric');
            app.ViscosityEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityEditFieldValueChanged, true);
            app.PresssureEditFieldLabel = uilabel(app.Panel);
            app.PresssureEditFieldLabel.Text = 'Presssure';
            app.PresssureEditField = uieditfield(app.Panel, 'numeric');
            app.PresssureEditField.ValueChangedFcn = createCallbackFcn(app, @PresssureEditFieldValueChanged, true);
            app.okButton = uibutton(app.Panel, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'ok';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'PABS'; 'QR'; 'Pitch'; 'Diameter'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.QRButton = uiradiobutton(app.ButtonGroup);
            app.QRButton.Text = 'QR';
            app.QRButton.Value = true;
            app.PABSButton = uiradiobutton(app.ButtonGroup);
            app.PABSButton.Text = 'PABS';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pumpSqure

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end